classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        RunCantileverButton             matlab.ui.control.Button
        ElapsedTimesEditField           matlab.ui.control.NumericEditField
        ElapsedTimesEditFieldLabel      matlab.ui.control.Label
        FixedNodesLamp                  matlab.ui.control.Lamp
        FixedNodesLampLabel             matlab.ui.control.Label
        ForceNodesLamp                  matlab.ui.control.Lamp
        ForceNodesLampLabel             matlab.ui.control.Label
        ObjectiveNodesLamp              matlab.ui.control.Lamp
        ObjectiveNodesLampLabel         matlab.ui.control.Label
        objDirDropDown                  matlab.ui.control.DropDown
        alongDropDownLabel              matlab.ui.control.Label
        VolumeConstraintEditField       matlab.ui.control.NumericEditField
        VolumeConstraintEditFieldLabel  matlab.ui.control.Label
        MaxVMStressMPaLabel             matlab.ui.control.Label
        DeselectNodesButton             matlab.ui.control.Button
        ResetFixedBCsButton             matlab.ui.control.Button
        ResetObjectiveButton            matlab.ui.control.Button
        ResetForceButton                matlab.ui.control.Button
        RunOptimizationButton           matlab.ui.control.Button
        ForceDirectionKnob              matlab.ui.control.DiscreteKnob
        ForceDirectionKnobLabel         matlab.ui.control.Label
        ApplyObjectivetoSelectedNodesButton  matlab.ui.control.Button
        ChooseObjectiveDropDown         matlab.ui.control.DropDown
        ChooseObjectiveDropDownLabel    matlab.ui.control.Label
        MaxVMStressMPaEditField         matlab.ui.control.NumericEditField
        ChooseDomainDropDown            matlab.ui.control.DropDown
        ChooseDomainDropDownLabel       matlab.ui.control.Label
        OptimizedOutputLabel            matlab.ui.control.Label
        StressOutputLabel               matlab.ui.control.Label
        SelectBoundaryConditionsLabel   matlab.ui.control.Label
        spacer                          matlab.ui.control.Label
        ApplyForcetoSelectedNodesButton  matlab.ui.control.Button
        FixSelectedNodesButton          matlab.ui.control.Button
        TotalAppliedForceNEditField     matlab.ui.control.NumericEditField
        TotalAppliedForceNEditFieldLabel  matlab.ui.control.Label
        MaterialThicknessmmEditField    matlab.ui.control.NumericEditField
        MaterialThicknessmmEditFieldLabel  matlab.ui.control.Label
        PoissonsRatioEditField          matlab.ui.control.NumericEditField
        PoissonsRatioEditFieldLabel     matlab.ui.control.Label
        YoungsModulusGPaEditField       matlab.ui.control.NumericEditField
        YoungsModulusGPaEditFieldLabel  matlab.ui.control.Label
        RunFEAButton                    matlab.ui.control.Button
        ClearAllButton                  matlab.ui.control.Button
        optimizedAxes                   matlab.ui.control.UIAxes
        stressAxes                      matlab.ui.control.UIAxes
        selectionAxes                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        
        E = 211;            % Youngs Modulus, GPa
        v = 0.29;           % Poissons ration, unitless
        t = 10;             % Material thickness, mm
        scale =     100;    % mm (how long is half a side of the plate)
        BCtype =    6;      % Boundary condition type, 0 is no prescribed force,
        % 1 is outward pressure, 2 is radial clockwise, 5 right
        % pressure, 6 upward pressure (to be made more elegant
        % later)
        FORCE = 1000;       % Applied force in Newtons
        nodeCord;           % n x 2 array which holds x and y coords of each node
        elemNodes;          % e x 6 array which holds associated node numbers for
        % each traingular element, first 3 are corner
        % nodes, last 3 are midnodes
        
        
        selectedNodes = zeros(1,2);     % highlighted nodes on the figure, first row useless
        forceNodes = zeros(1,2);        % nodes with force applied to them, first row useless
        objNodes = zeros(1,2);          % nodes w obj applied, first row useless
        fixedNodes = zeros(1,2);        % fixed nodes based on boundary conditions, first row useless
        
        forceNodesIndices = [];         % need the indices of all the coords in nodeCord
        objNodesIndices = [];
        fixedNodesIndices = [];
        
        finalNodeCord;      % final nodal coordinates after FEA is run
        disp;               % nodal displacement after FEA
        P;                  % reaction force at each node at equilib disp
        vmStress;           % von mises stress
        
        % vars for mouse position follower function
        x = 0;              % current x pos of mouse
        y = 0;              % current y pos of mouse
        x0;                 % starting x pos for box creation
        y0;                 % starting y pos for box creation
        mouseDown = false;  % used for drawing the box
        shiftDown = false;  % used for multiple node selections
        box;                % the plotted box
        
        runStiffness = true; % determines obj function
        
        forceDir = [-0.1,0]; % determines direction of load on the domain, the magnitude is only to make arrows look good
        
        volumeCon = 50; % of total vol allowed to be used in opt
        
    end
    
    methods (Access = private)
                             
        function clearSA(app)
            cla(app.selectionAxes)
            patch(app.selectionAxes,'vertices',app.nodeCord,'faces',app.elemNodes(:,1:3),'edgecol','k','facecol',[.8,.9,1]);
        end
        
        function resetAllAxes(app)
            cla(app.selectionAxes,"reset")
            cla(app.stressAxes,"reset")
            cla(app.optimizedAxes,"reset")
            
            app.selectionAxes.HandleVisibility = 'on';
            app.stressAxes.HandleVisibility = 'on';
            app.UIFigure.HandleVisibility = 'on';
            
            set(app.selectionAxes,'visible','off');
            set(app.stressAxes,'visible','off');
            set(app.optimizedAxes,'visible','off');
            
            app.selectionAxes.Toolbar.Visible = 'off';
        end
        
        function plotCurrent(app) % clears axes and replots node vectors
            clearSA(app);
            for i = 2:length(app.selectedNodes(:,1))
                plot(app.selectionAxes,app.selectedNodes(i,1),app.selectedNodes(i,2),'k*')
            end
            for i = 2:length(app.forceNodes(:,1))
                quiver(app.selectionAxes,app.forceNodes(i,1),app.forceNodes(i,2),app.forceDir(1),app.forceDir(2),'color','r','MaxHeadSize',5)
            end
            for i = 2:length(app.objNodes(:,1))
                plot(app.selectionAxes,app.objNodes(i,1),app.objNodes(i,2),'y*')
            end
            for i = 2:length(app.fixedNodes(:,1))
                plot(app.selectionAxes,app.fixedNodes(i,1),app.fixedNodes(i,2),'b*')
            end
        end
        
        function clearForceNodes(app) % clears forceNodes vector and removes them from plot
            app.forceNodes = zeros(1,2);
            plotCurrent(app);
            app.forceNodesIndices = [];
        end
        
        function clearObjNodes(app) % clears ObjNodes vector and removes them from plot
            app.objNodes = zeros(1,2);
            plotCurrent(app);
            app.objNodesIndices = [];
        end
        
        function clearFixedNodes(app) % clears fixedNodes vector and removes them from plot
            app.fixedNodes = zeros(1,2);
            plotCurrent(app);
            app.fixedNodesIndices = [];
        end
        
        function clearSelectedNodes(app) % clears selectedNodes vector and removes them from plot
            app.selectedNodes = zeros(1,2);
            plotCurrent(app);
        end
        
        function clearNodes(app)
            app.selectedNodes = zeros(1,2);
            app.forceNodes = zeros(1,2);
            app.fixedNodes = zeros(1,2);
            app.objNodes = zeros(1,2);
            app.forceNodesIndices = [];
            app.objNodesIndices = [];
            app.fixedNodesIndices = [];
        end
                
        function run(app,runOpt)
            tic
            %% [4] Initialize the density matrix based on the amount of elements, give
            % every element a density of ~1
            [elemN,~] = size(app.elemNodes);
            roe = ones(elemN,1)*0.96;
            
            %% [6] Define constraints
            constraint = app.fixedNodesIndices; % These are nodes that are fixed.
            uPrescribed = []; % There are no prescribed displacements for this problem
            
            forceBCs = zeros(size(app.forceNodesIndices)) + app.FORCE/length(app.forceNodesIndices);
            
            % [node index, total force on the node, type of force (helps determine force components)]
            forceBCs = [app.forceNodesIndices,forceBCs,zeros(size(app.forceNodesIndices))+app.BCtype];
            Pfree = makePfree(app.nodeCord,forceBCs,app.ForceDirectionKnob.Value);
            prescBCs = [];
            
            %% [8] Scale the nodal coordinates
            app.nodeCord = (app.nodeCord* app.scale);
            
            %% [9] Calculate the area of each element, m^2
            elemArea = zeros(length(app.elemNodes),1);
            for i = 1:length(app.elemNodes)
                n1 = app.nodeCord(app.elemNodes(i,1),:);
                n2 = app.nodeCord(app.elemNodes(i,2),:);
                n3 = app.nodeCord(app.elemNodes(i,3),:);
                x = [n1(1),n2(1),n3(1)]/1000; %convert x cord to m
                y = [n1(2),n2(2),n3(2)]/1000; %convert y cord to m
                elemArea(i) = polyarea(x,y); %here the integral is over the area, so
                %"volume" is really just area for 2D
            end
            
            %% [11] Performing the FEA
            [app.finalNodeCord,app.vmStress,app.disp,app.P] = finite2dTriQuadElements(...
                app.E,app.v,app.t,app.nodeCord,app.elemNodes,elemArea,constraint,prescBCs,Pfree,roe);
            plotFEA(app.vmStress,app.elemNodes,app.finalNodeCord,roe,app.stressAxes,app.MaxVMStressMPaEditField)
            
            % this function is used to plot steps throughout optimization
            % must define nested function outside any program control statements (if, switch, for, etc) -- toggle
            function stop = outFcn(x,optimValues,state)
                stop = false;
                % temp = roundn(optimValues.iteration/5+5,1); if you wanna show fewer iterations as simulation goes on, use this in mod
                if ~mod(optimValues.iteration,20) % how often should the plot update TODO: maybe add in updating stress plot, would need to run FEA in function
                switch state
                    case 'init'
                        hold on
                    case 'iter'
                        for k = 1:elemN
                            p(k).CData = x(k);
                        end
                        colormap(app.optimizedAxes,flipud(gray(256)));
                        colorbar(app.optimizedAxes)
                        axes(app.optimizedAxes)
                        axis equal
                        view(2)
                    case 'done'
                        hold off
                    otherwise
                end
                end
            end
            
            if runOpt % this makes it so if the run optimization button is pressed, it'll run the FEA again so it updates and do the opt
                % if only the runFEA button is hit, it will only update the FEA stress plot

                % initializes the patch array for showing the iterations
                % TODO: figure out how to preallocate size of patch array
               for j = 1:elemN 
                    corner_nodes = app.elemNodes(:,1:3);
                    xy = app.nodeCord(corner_nodes(j,1:3),:);
                    p(j) = patch(app.optimizedAxes,xy(:,1),xy(:,2),roe(j));
                end
                
                lb = zeros(size(roe));
                ub = ones(size(roe));
                A = (100/app.volumeCon)*ones(size(roe')); % volumeCon is max allowable percentage of total domain vol used
                b = sum(ones(size(roe)));
                Aeq = [];
                beq = [];
                
                if app.runStiffness % for now this variable works bc there are only 2 options, decides inputs to fmincon
                    f = @(x) StiffnessSensitivity(app.E,app.v,app.t,app.nodeCord,app.elemNodes,elemArea,constraint,prescBCs,Pfree,x,app.objNodesIndices,app.objDirDropDown.Value);
                    nonlcon = [];
                    options = optimoptions(@fmincon,'SpecifyObjectiveGradient',true,'OutputFcn',@outFcn); % include if you wanna show updated optimization -- toggle
                    roe = MMA(roe,app.volumeCon,f);
                    
                else
                    f = @(x) ComplianceSensitivity(app.E,app.v,app.t,app.nodeCord,app.elemNodes,elemArea,constraint,prescBCs,Pfree,x,app.objNodesIndices,app.objDirDropDown.Value);
                    if app.objDirDropDown.Value == "y-axis" % this determines stiffness direction
                        indices = zeros(size(app.objNodesIndices));
                        for i = 1:length(app.objNodesIndices)
                            indices(i) = app.objNodesIndices(i)*2-1;
                        end
                    elseif app.objDirDropDown.Value == "x-axis"
                        indices = zeros(size(app.objNodesIndices));
                        for i = 1:length(app.objNodesIndices)
                            indices(i) = app.objNodesIndices(i)*2;
                        end
                    end
                    initEnergy = app.P(indices)'*app.disp(indices);
                    nonlcon = @(x) nlconComp(app.E,app.v,app.t,app.nodeCord,app.elemNodes,elemArea,constraint,prescBCs,Pfree,x,app.objNodesIndices,app.objDirDropDown.Value,initEnergy); % might need objDir
                    options = optimoptions('fmincon','SpecifyObjectiveGradient',true,'SpecifyConstraintGradient',true,'OutputFcn',@outFcn); % include if you wanna show updated optimization
                    roe = MMA(roe,app.volumeCon,f,nonlcon);
                end
                                                             
                plotOpt(app.elemNodes,app.nodeCord,roe,app.optimizedAxes);
                
                % Perform and plot the FEA with final roe vals to get optimized design stress field
                [app.finalNodeCord,app.vmStress,app.disp,app.P] = finite2dTriQuadElements(...
                app.E,app.v,app.t,app.nodeCord,app.elemNodes,elemArea,constraint,prescBCs,Pfree,roe);
                vmStress2 = zeros(size(app.vmStress));
                vmStress2(roe>0.3) = app.vmStress(roe>0.3);
            
                plotFEA(vmStress2,app.elemNodes,app.finalNodeCord,roe,app.stressAxes,app.MaxVMStressMPaEditField) % add once its fixed
            end
            
            app.nodeCord = (app.nodeCord/app.scale); % so that we can reset plot
            app.ElapsedTimesEditField.Value = toc;
        end
        
        function array = noDuplicates(~,nodes,temp)
            remove = ismember(nodes,temp(2:end,:),'rows'); % logical array of indices to remove
            nodes(remove,:) = [];
            if length(temp(:,1)) >= 1 % at least a single point
                array = [nodes;temp(2:end,:)]; % re-add removed and new nodes
            elseif ~any(remove)
                array = [nodes;temp(2:end,:)]; % if a new point is selected
            end 
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % pre-processing            
            addpath(strcat(cd,'\distmesh'))
            addpath(strcat(cd,'\quadTriElemFEA'))
            
            app.selectionAxes.HandleVisibility = 'on';
            app.stressAxes.HandleVisibility = 'on';
            app.UIFigure.HandleVisibility = 'on';
            
            set(app.selectionAxes,'visible','off');
            set(app.stressAxes,'visible','off');
            set(app.optimizedAxes,'visible','off');
            
            % add default values to GUI
            app.YoungsModulusGPaEditField.Value = app.E;
            app.PoissonsRatioEditField.Value = app.v;
            app.MaterialThicknessmmEditField.Value = app.t;
            app.TotalAppliedForceNEditField.Value = app.FORCE;
            app.VolumeConstraintEditField.Value = app.volumeCon;
        end

        % Window button motion function: UIFigure
        function UIFigureWindowButtonMotion(app, event)
            app.x = app.selectionAxes.CurrentPoint(1,1); % to access x and y outside the callback
            app.y = app.selectionAxes.CurrentPoint(1,2);
            if app.mouseDown
                delete(app.box)
                app.box = plot(app.selectionAxes,[app.x0,app.x0,app.x,app.x,app.x0],[app.y0,app.y,app.y,app.y0,app.y0],'k');
            end
        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)
            app.mouseDown = true;
            app.x0 = app.selectionAxes.CurrentPoint(1,1);
            app.y0 = app.selectionAxes.CurrentPoint(1,2);
            hold on
        end

        % Window button up function: UIFigure
        function UIFigureWindowButtonUp(app, event)
            app.mouseDown = false;
            delete(app.box)
            j = 0;
            shiftD = app.shiftDown; % if shift was down add selection to selectedNodes
            temp = []; % stores nodes prior to manipulating app.selectedNodes
            
            %% create temp array depending on box or point selection
            distance = @(x1,y1,x2,y2) sqrt((y2-y1)^2+(x2-x1)^2);
            if distance(app.x0,app.y0,app.x,app.y) > 0.1 % basically if actual box was drawn
                temp = zeros(size(app.nodeCord)); % preallocate size
                for i = 1:length(app.nodeCord(:,1))
                    if (app.nodeCord(i,1) > app.x0 && app.nodeCord(i,2) < app.y0) && ... % maybe there is a better way to choose points in box
                            (app.nodeCord(i,1) < app.x && app.nodeCord(i,2) > app.y)
                        j = j+1;
                        temp(j,:) = [app.nodeCord(i,1),app.nodeCord(i,2)];
                    end
                end
                temp = temp(1:j,:); % removes excess zeros from preallocation, idk if this is actually more efficient
            else % just one point was selected
                for i = 1:length(app.nodeCord(:,1))
                    if distance(app.x,app.y,app.nodeCord(i,1),app.nodeCord(i,2)) < 0.025 % within range of a node
                        temp = [app.nodeCord(i,1),app.nodeCord(i,2)];
                    end
                end
            end
            
            %% determine how to manipulate app.selectedNodes based on shift key press
            if shiftD && ~isempty(temp) % temp is only empty if you miss a node when pressing
                remove = ismember(app.selectedNodes,temp,'rows'); % logical array of indices to remove
                app.selectedNodes(remove,:) = [];
                if length(temp(:,1)) > 1 % more than a single point
                    app.selectedNodes = [app.selectedNodes;temp]; % re-add removed and new nodes
                elseif ~any(remove)
                    app.selectedNodes = [app.selectedNodes;temp]; % if a new point is selected
                end
            elseif ~isempty(temp) % so misclicks don't erase app.selectedNodes
                app.selectedNodes = [zeros(1,2);temp]; % reset app.selectedNodes if no shift
            end
            plotCurrent(app);
        end

        % Value changed function: ChooseDomainDropDown
        function ChooseDomainDropDownValueChanged(app, event)
            value = app.ChooseDomainDropDown.Value;
            
            % reset axes
            app.resetAllAxes;
            app.clearNodes;
            
            % call mesher
            if value == "Circle"
                fd=@(p) sqrt(sum(p.^2,2))-1;
                [app.nodeCord,app.elemNodes]=plotlessDistmesh2d(fd,@huniform,0.1,[-1,-1;1,1],[]);
            elseif value == "Square"
                fd=@(p) drectangle(p,-1,1,-1,1);
                pfix=[-1,-1;-1,1;1,-1;1,1];
                [app.nodeCord,app.elemNodes]=plotlessDistmesh2d(fd,@huniform,0.1,[-1,-1;1,1],pfix);
            elseif value == "Rectangle"
                fd=@(p) drectangle(p,-1,1,-.5,.5);
                pfix=[-1,-.5;-1,.5;1,-.5;1,.5];
                [app.nodeCord,app.elemNodes]=plotlessDistmesh2d(fd,@huniform,0.1,[-1,-.5;1,.5],pfix);
            elseif value == "Square With Hole"
                fd=@(p) ddiff(drectangle(p,-1,1,-1,1),dcircle(p,0,0,0.5));
                fh=@(p) 0.05+0.3*dcircle(p,0,0,0.02);
                [app.nodeCord,app.elemNodes]=plotlessDistmesh2d(fd,fh,0.04,[-1,-1;1,1],[-1,-1;-1,1;1,-1;1,1]);
            end
            [app.elemNodes,app.nodeCord] = addMidNodes(app.nodeCord,app.elemNodes);
            patch(app.selectionAxes,'vertices',app.nodeCord,'faces',app.elemNodes(:,1:3),'edgecol','k','facecol',[.8,.9,1]);
            
            axes(app.selectionAxes)
            axis equal
            
            % scale axes
            xl = xlim(app.selectionAxes);
            yl = ylim(app.selectionAxes);
            xl = xl*1.1;
            yl = yl*1.1;
            xlim(app.selectionAxes,xl)
            ylim(app.selectionAxes,yl)
            
            xlim(app.stressAxes,xl*app.scale)
            ylim(app.stressAxes,yl*app.scale)
            
            xlim(app.optimizedAxes,xl*app.scale)
            ylim(app.optimizedAxes,yl*app.scale)
        end

        % Value changed function: ChooseObjectiveDropDown
        function ChooseObjectiveDropDownValueChanged(app, event)
            value = app.ChooseObjectiveDropDown.Value;
            if value == "Stiffness"
                app.runStiffness = true; % probably will need a new way of doing this when these aren't the only objectives
            elseif value == "Compliance"
                app.runStiffness = false;
            end
        end

        % Value changed function: ForceDirectionKnob
        function ForceDirectionKnobValueChanged(app, event)
            clearSA(app);
            
            value = app.ForceDirectionKnob.Value;
            if value == "Left"
                app.forceDir = [-0.1,0];
            elseif value == "Right"
                app.forceDir = [0.1,0];
            elseif value == "Up"
                app.forceDir = [0,0.1];
            elseif value == "Down"
                app.forceDir = [0,-0.1];
            end
            plotCurrent(app);
        end

        % Button pushed function: ApplyForcetoSelectedNodesButton
        function ApplyForcetoSelectedNodesButtonPushed(app, event)
            ism = ismember(app.nodeCord,app.selectedNodes,'rows');
            temp = app.selectedNodes;
            tempIndex = find(ism);
            if ~isempty(app.forceNodes(2:end,:))
                app.forceNodes = noDuplicates(app,app.forceNodes,temp);
                app.forceNodesIndices = noDuplicates(app,app.forceNodesIndices,tempIndex);
            else
                app.forceNodes = temp;
                app.forceNodesIndices = tempIndex;
            end
            plotCurrent(app)
        end

        % Button pushed function: 
        % ApplyObjectivetoSelectedNodesButton
        function ApplyObjectivetoSelectedNodesButtonPushed(app, event)
            ism = ismember(app.nodeCord,app.selectedNodes,'rows');
            temp = app.selectedNodes;
            tempIndex = find(ism);
            if ~isempty(app.objNodes(2:end,:))
                app.objNodes = noDuplicates(app,app.objNodes,temp);
                app.objNodesIndices = noDuplicates(app,app.objNodesIndices,tempIndex);
            else
                app.objNodes = temp;
                app.objNodesIndices = tempIndex;
            end
            plotCurrent(app)
        end

        % Button pushed function: FixSelectedNodesButton
        function FixSelectedNodesButtonPushed(app, event)
            ism = ismember(app.nodeCord,app.selectedNodes,'rows');
            temp = app.selectedNodes;
            tempIndex = find(ism);
            if ~isempty(app.fixedNodes(2:end,:))
                app.fixedNodes = noDuplicates(app,app.fixedNodes,temp);
                app.fixedNodesIndices = noDuplicates(app,app.fixedNodesIndices,tempIndex);
            else
                app.fixedNodes = temp;
                app.fixedNodesIndices = tempIndex;
            end
            plotCurrent(app)
        end

        % Button pushed function: DeselectNodesButton
        function DeselectNodesButtonPushed(app, event)
            app.clearSelectedNodes;
        end

        % Button pushed function: ClearAllButton
        function ClearAllButtonPushed(app, event)
            app.clearNodes;
            app.clearSA;
        end

        % Button pushed function: ResetForceButton
        function ResetForceButtonPushed(app, event)
            clearForceNodes(app);
        end

        % Button pushed function: ResetObjectiveButton
        function ResetObjectiveButtonPushed(app, event)
            clearObjNodes(app);
        end

        % Button pushed function: ResetFixedBCsButton
        function ResetFixedBCsButtonPushed(app, event)
            clearFixedNodes(app);
        end

        % Button pushed function: RunFEAButton
        function RunFEAButtonPushed(app, event)
            run(app,false);
        end

        % Button pushed function: RunOptimizationButton
        function RunOptimizationButtonPushed(app, event)
            cla(app.optimizedAxes)
            run(app,true);
        end

        % Value changed function: YoungsModulusGPaEditField
        function YoungsModulusGPaEditFieldValueChanged(app, event)
            value = app.YoungsModulusGPaEditField.Value;
            app.E = value;
        end

        % Value changed function: PoissonsRatioEditField
        function PoissonsRatioEditFieldValueChanged(app, event)
            value = app.PoissonsRatioEditField.Value;
            app.v = value;
        end

        % Value changed function: TotalAppliedForceNEditField
        function TotalAppliedForceNEditFieldValueChanged(app, event)
            value = app.TotalAppliedForceNEditField.Value;
            app.FORCE = value;
        end

        % Value changed function: VolumeConstraintEditField
        function VolumeConstraintEditFieldValueChanged(app, event)
            value = app.VolumeConstraintEditField.Value;
            if (value>100)
                app.volumeCon = 100;
            else
                app.volumeCon = value;
            end
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            if key == "shift"
                app.shiftDown = true;
            end
        end

        % Key release function: UIFigure
        function UIFigureKeyRelease(app, event)
            key = event.Key;
            if key == "shift"
                app.shiftDown = false;
            end
        end

        % Button pushed function: RunCantileverButton
        function RunCantileverButtonPushed(app, event)
             % choose rectangle
            app.ChooseDomainDropDown.Value = "Rectangle";
            app.ChooseDomainDropDownValueChanged(app);
            app.runStiffness = false;
            hold on
            
            % choose fixed nodes
            app.selectedNodes = [0,0; app.nodeCord(app.nodeCord < -1 + 10E-5, :)];
            plotCurrent(app);
            app.FixSelectedNodesButtonPushed(app);
        
            % choose forceNodes/objNodes
            app.selectedNodes =  [0,0; app.nodeCord(app.nodeCord > 1 - 10E-5, :)];
            plotCurrent(app);
            app.ApplyForcetoSelectedNodesButtonPushed(app);
            app.ApplyObjectivetoSelectedNodesButtonPushed(app);
            
            % change objDir
            app.objDirDropDown.Value = "y-axis";
            
            % change forceNodes Dir
            app.ForceDirectionKnob.Value = "Down";
            app.ForceDirectionKnobValueChanged(app);
            
            % hit Run Optimization
            %app.RunOptimizationButtonPushed(app);
            app.RunOptimizationButtonPushed(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.UIFigure.WindowButtonUpFcn = createCallbackFcn(app, @UIFigureWindowButtonUp, true);
            app.UIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @UIFigureWindowButtonMotion, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.UIFigure.KeyReleaseFcn = createCallbackFcn(app, @UIFigureKeyRelease, true);
            app.selectionAxes = uiaxes(app.UIFigure);
            app.selectionAxes.XTick = [];
            app.selectionAxes.YTick = [];
            app.stressAxes = uiaxes(app.UIFigure);
            app.stressAxes.XTick = [];
            app.stressAxes.YTick = [];
            app.optimizedAxes = uiaxes(app.UIFigure);
            app.optimizedAxes.XTick = [];
            app.optimizedAxes.YTick = [];
            app.ClearAllButton = uibutton(app.UIFigure, 'push');
            app.ClearAllButton.ButtonPushedFcn = createCallbackFcn(app, @ClearAllButtonPushed, true);
            app.ClearAllButton.Text = 'Clear All';
            app.RunFEAButton = uibutton(app.UIFigure, 'push');
            app.RunFEAButton.ButtonPushedFcn = createCallbackFcn(app, @RunFEAButtonPushed, true);
            app.RunFEAButton.Text = 'Run FEA';
            app.YoungsModulusGPaEditFieldLabel = uilabel(app.UIFigure);
            app.YoungsModulusGPaEditFieldLabel.Text = 'Young''s Modulus (GPa)';
            app.YoungsModulusGPaEditField = uieditfield(app.UIFigure, 'numeric');
            app.YoungsModulusGPaEditField.ValueChangedFcn = createCallbackFcn(app, @YoungsModulusGPaEditFieldValueChanged, true);
            app.PoissonsRatioEditFieldLabel = uilabel(app.UIFigure);
            app.PoissonsRatioEditFieldLabel.Text = 'Poisson''s Ratio';
            app.PoissonsRatioEditField = uieditfield(app.UIFigure, 'numeric');
            app.PoissonsRatioEditField.ValueChangedFcn = createCallbackFcn(app, @PoissonsRatioEditFieldValueChanged, true);
            app.MaterialThicknessmmEditFieldLabel = uilabel(app.UIFigure);
            app.MaterialThicknessmmEditFieldLabel.Text = 'Material Thickness (mm)';
            app.MaterialThicknessmmEditField = uieditfield(app.UIFigure, 'numeric');
            app.TotalAppliedForceNEditFieldLabel = uilabel(app.UIFigure);
            app.TotalAppliedForceNEditFieldLabel.Text = 'Total Applied Force (N)';
            app.TotalAppliedForceNEditField = uieditfield(app.UIFigure, 'numeric');
            app.TotalAppliedForceNEditField.ValueChangedFcn = createCallbackFcn(app, @TotalAppliedForceNEditFieldValueChanged, true);
            app.FixSelectedNodesButton = uibutton(app.UIFigure, 'push');
            app.FixSelectedNodesButton.ButtonPushedFcn = createCallbackFcn(app, @FixSelectedNodesButtonPushed, true);
            app.FixSelectedNodesButton.Text = 'Fix Selected Nodes';
            app.ApplyForcetoSelectedNodesButton = uibutton(app.UIFigure, 'push');
            app.ApplyForcetoSelectedNodesButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyForcetoSelectedNodesButtonPushed, true);
            app.ApplyForcetoSelectedNodesButton.Text = 'Apply Force to Selected Nodes';
            app.spacer = uilabel(app.UIFigure);
            app.spacer.Text = '';
            app.SelectBoundaryConditionsLabel = uilabel(app.UIFigure);
            app.SelectBoundaryConditionsLabel.Text = 'Select Boundary Conditions:';
            app.StressOutputLabel = uilabel(app.UIFigure);
            app.StressOutputLabel.Text = 'Stress Output';
            app.OptimizedOutputLabel = uilabel(app.UIFigure);
            app.OptimizedOutputLabel.Text = 'Optimized Output';
            app.ChooseDomainDropDownLabel = uilabel(app.UIFigure);
            app.ChooseDomainDropDownLabel.Text = 'Choose Domain';
            app.ChooseDomainDropDown = uidropdown(app.UIFigure);
            app.ChooseDomainDropDown.Items = {'-', 'Square', 'Rectangle', 'Circle', 'Square With Hole'};
            app.ChooseDomainDropDown.ValueChangedFcn = createCallbackFcn(app, @ChooseDomainDropDownValueChanged, true);
            app.ChooseDomainDropDown.Value = '-';
            app.MaxVMStressMPaEditField = uieditfield(app.UIFigure, 'numeric');
            app.ChooseObjectiveDropDownLabel = uilabel(app.UIFigure);
            app.ChooseObjectiveDropDownLabel.Text = 'Choose Objective';
            app.ChooseObjectiveDropDown = uidropdown(app.UIFigure);
            app.ChooseObjectiveDropDown.Items = {'Stiffness', 'Compliance'};
            app.ChooseObjectiveDropDown.ValueChangedFcn = createCallbackFcn(app, @ChooseObjectiveDropDownValueChanged, true);
            app.ChooseObjectiveDropDown.Value = 'Stiffness';
            app.ApplyObjectivetoSelectedNodesButton = uibutton(app.UIFigure, 'push');
            app.ApplyObjectivetoSelectedNodesButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyObjectivetoSelectedNodesButtonPushed, true);
            app.ApplyObjectivetoSelectedNodesButton.Text = 'Apply Objective to Selected Nodes';
            app.ForceDirectionKnobLabel = uilabel(app.UIFigure);
            app.ForceDirectionKnobLabel.Text = 'Force Direction';
            app.ForceDirectionKnob = uiknob(app.UIFigure, 'discrete');
            app.ForceDirectionKnob.Items = {'Left', 'Right', 'Up', 'Down'};
            app.ForceDirectionKnob.ValueChangedFcn = createCallbackFcn(app, @ForceDirectionKnobValueChanged, true);
            app.ForceDirectionKnob.Value = 'Left';
            app.RunOptimizationButton = uibutton(app.UIFigure, 'push');
            app.RunOptimizationButton.ButtonPushedFcn = createCallbackFcn(app, @RunOptimizationButtonPushed, true);
            app.RunOptimizationButton.Text = 'Run Optimization';
            app.ResetForceButton = uibutton(app.UIFigure, 'push');
            app.ResetForceButton.ButtonPushedFcn = createCallbackFcn(app, @ResetForceButtonPushed, true);
            app.ResetForceButton.Text = 'Reset Force';
            app.ResetObjectiveButton = uibutton(app.UIFigure, 'push');
            app.ResetObjectiveButton.ButtonPushedFcn = createCallbackFcn(app, @ResetObjectiveButtonPushed, true);
            app.ResetObjectiveButton.Text = 'Reset Objective';
            app.ResetFixedBCsButton = uibutton(app.UIFigure, 'push');
            app.ResetFixedBCsButton.ButtonPushedFcn = createCallbackFcn(app, @ResetFixedBCsButtonPushed, true);
            app.ResetFixedBCsButton.Text = 'Reset Fixed BC''s';
            app.DeselectNodesButton = uibutton(app.UIFigure, 'push');
            app.DeselectNodesButton.ButtonPushedFcn = createCallbackFcn(app, @DeselectNodesButtonPushed, true);
            app.DeselectNodesButton.Text = 'Deselect Nodes';
            app.MaxVMStressMPaLabel = uilabel(app.UIFigure);
            app.MaxVMStressMPaLabel.Text = 'Max VM Stress (MPa)';
            app.VolumeConstraintEditFieldLabel = uilabel(app.UIFigure);
            app.VolumeConstraintEditFieldLabel.Text = 'Volume Constraint (%)';
            app.VolumeConstraintEditField = uieditfield(app.UIFigure, 'numeric');
            app.VolumeConstraintEditField.ValueChangedFcn = createCallbackFcn(app, @VolumeConstraintEditFieldValueChanged, true);
            app.alongDropDownLabel = uilabel(app.UIFigure);
            app.alongDropDownLabel.Text = 'along';
            app.objDirDropDown = uidropdown(app.UIFigure);
            app.objDirDropDown.Items = {'x-axis', 'y-axis', 'both'};
            app.objDirDropDown.Value = 'x-axis';
            app.ObjectiveNodesLampLabel = uilabel(app.UIFigure);
            app.ObjectiveNodesLampLabel.Text = 'Objective Nodes';
            app.ObjectiveNodesLamp = uilamp(app.UIFigure);
            app.ForceNodesLampLabel = uilabel(app.UIFigure);
            app.ForceNodesLampLabel.Text = 'Force Nodes';
            app.ForceNodesLamp = uilamp(app.UIFigure);
            app.FixedNodesLampLabel = uilabel(app.UIFigure);
            app.FixedNodesLampLabel.Text = 'Fixed Nodes';
            app.FixedNodesLamp = uilamp(app.UIFigure);
            app.ElapsedTimesEditFieldLabel = uilabel(app.UIFigure);
            app.ElapsedTimesEditFieldLabel.Text = 'Elapsed Time (s)';
            app.ElapsedTimesEditField = uieditfield(app.UIFigure, 'numeric');
            app.RunCantileverButton = uibutton(app.UIFigure, 'push');
            app.RunCantileverButton.ButtonPushedFcn = createCallbackFcn(app, @RunCantileverButtonPushed, true);
            app.RunCantileverButton.Text = 'Run Cantilever';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end